classdef bytepipe_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        ConnectTool                    matlab.ui.container.toolbar.PushTool
        ProfileBrowseTool              matlab.ui.container.toolbar.PushTool
        ConnectedTool                  matlab.ui.container.toolbar.ToggleTool
        LogText                        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        ConnectionTab                  matlab.ui.container.Tab
        AddressEditField               matlab.ui.control.EditField
        AddressEditFieldLabel          matlab.ui.control.Label
        ConnectionTypeDropDown         matlab.ui.control.DropDown
        ConnectionTypeLabel            matlab.ui.control.Label
        Logo                           matlab.ui.control.Image
        connection_info                matlab.ui.control.Table
        ConnectButton                  matlab.ui.control.Button
        ProfileTab                     matlab.ui.container.Tab
        ProfileTree                    matlab.ui.container.Tree
        WriteProfile                   matlab.ui.control.Button
        SelectProfileButton            matlab.ui.control.Button
        FilterTab                      matlab.ui.container.Tab
        FilterDesignerPanel            matlab.ui.container.Panel
        FirAmplitudeUnit               matlab.ui.control.DropDown
        FirResponseType                matlab.ui.control.DropDown
        FirDesignButton                matlab.ui.control.Button
        FirOrderEditField              matlab.ui.control.NumericEditField
        OrderEditFieldLabel            matlab.ui.control.Label
        FirFrequencyUnit               matlab.ui.control.DropDown
        FirFsEditField                 matlab.ui.control.NumericEditField
        FsEditFieldLabel               matlab.ui.control.Label
        FirFpassEditField              matlab.ui.control.NumericEditField
        FpassEditFieldLabel            matlab.ui.control.Label
        FirFstopEditField              matlab.ui.control.NumericEditField
        FstopEditFieldLabel            matlab.ui.control.Label
        FirApassEditField              matlab.ui.control.NumericEditField
        ApassEditFieldLabel            matlab.ui.control.Label
        FirAstopEditField              matlab.ui.control.NumericEditField
        AstopEditFieldLabel            matlab.ui.control.Label
        FirDesignMethodDropDown        matlab.ui.control.DropDown
        Adrv9001Panel                  matlab.ui.container.Panel
        PFirWriteButton                matlab.ui.control.Button
        PFirReadButton                 matlab.ui.control.Button
        PFirChannel                    matlab.ui.control.DropDown
        PFirPort                       matlab.ui.control.DropDown
        PFirCoefficientSetButtonGroup  matlab.ui.container.ButtonGroup
        PFirDButton                    matlab.ui.control.RadioButton
        PFirCButton                    matlab.ui.control.RadioButton
        PFirBButton                    matlab.ui.control.RadioButton
        PFirAButton                    matlab.ui.control.RadioButton
        PFirPlot                       matlab.ui.control.UIAxes
        TransmitTab                    matlab.ui.container.Tab
        Tx1EnableButton                matlab.ui.control.StateButton
        Tx2Panel                       matlab.ui.container.Panel
        DataSourceDropDown_2           matlab.ui.control.DropDown
        DataSourceDropDown_2Label      matlab.ui.control.Label
        Tx2RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel_2      matlab.ui.control.Label
        Tx1Panel                       matlab.ui.container.Panel
        DataSourceDropDown             matlab.ui.control.DropDown
        DataSourceDropDownLabel        matlab.ui.control.Label
        Tx2IfFreq                      matlab.ui.control.NumericEditField
        IFFreqKHzLabel_2               matlab.ui.control.Label
        Tx2SampleRate                  matlab.ui.control.NumericEditField
        SampleRateMHzLabel_2           matlab.ui.control.Label
        Tx2Attn                        matlab.ui.control.NumericEditField
        AttenuationdBEditFieldLabel_2  matlab.ui.control.Label
        Tx2CarrierFreq                 matlab.ui.control.NumericEditField
        FrequencyMHzLabel_2            matlab.ui.control.Label
        Tx2BoostEnabled                matlab.ui.control.CheckBox
        Tx1RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel        matlab.ui.control.Label
        Tx1IfFreq                      matlab.ui.control.NumericEditField
        IFFreqKHzLabel                 matlab.ui.control.Label
        Tx1SampleRate                  matlab.ui.control.NumericEditField
        SampleRateMHzLabel             matlab.ui.control.Label
        Tx1Attn                        matlab.ui.control.NumericEditField
        AttenuationdBEditFieldLabel    matlab.ui.control.Label
        Tx1CarrierFreq                 matlab.ui.control.NumericEditField
        FrequencyMHzLabel              matlab.ui.control.Label
        Tx1BoostEnabled                matlab.ui.control.CheckBox
        TxPlot                         matlab.ui.control.UIAxes
        ReceiveTab                     matlab.ui.container.Tab
        Rx1EnableButton                matlab.ui.control.StateButton
        Rx2Panel                       matlab.ui.container.Panel
        Rx2RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel_4      matlab.ui.control.Label
        Rx2IfFreq                      matlab.ui.control.NumericEditField
        IFFreqKHzLabel_4               matlab.ui.control.Label
        Rx2SampleRate                  matlab.ui.control.NumericEditField
        SampleRateMHzLabel_4           matlab.ui.control.Label
        Rx2Gain                        matlab.ui.control.NumericEditField
        AttenuationdBEditFieldLabel_4  matlab.ui.control.Label
        Rx2CarrierFreq                 matlab.ui.control.NumericEditField
        FrequencyMHzLabel_4            matlab.ui.control.Label
        Rx1Panel                       matlab.ui.container.Panel
        Rx1RadioState                  matlab.ui.control.DropDown
        RadioStateDropDownLabel_3      matlab.ui.control.Label
        Rx1IfFreq                      matlab.ui.control.NumericEditField
        IFFreqKHzLabel_3               matlab.ui.control.Label
        Rx1SampleRate                  matlab.ui.control.NumericEditField
        SampleRateMHzLabel_3           matlab.ui.control.Label
        Rx1Gain                        matlab.ui.control.NumericEditField
        GaindBLabel                    matlab.ui.control.Label
        Rx1CarrierFreq                 matlab.ui.control.NumericEditField
        FrequencyMHzLabel_3            matlab.ui.control.Label
        RxPlot                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        rpmsg; 
        rflan;
        profileDir = fileparts(mfilename('fullpath'));
        profileValid = false;

        PFirDesignCoeff;

        rxTimer;

    end

    methods (Access = private)

        function str = App_GetRadioState(app, port, channel )

            if( app.profileValid )
                state = app.rpmsg.Adrv9001_GetRadioState(port, channel);

                if(contains(state,'ENABLED'))
                    str = {'ENABLED'};
                elseif(contains(state,'PRIMED'))
                    str = {'PRIMED'};
                elseif(contains(state,'CALIBRATED'))
                    str = {'CALIBRATED'};
                else
                    str = {'STANDBY'};
                end

                app.UpdateLogText('Adrv9001_GetRadioState');
            else
                str = {'STANDBY'};
            end
        end

        function state = App_SetRadioState(app, port, channel, value )

            if(contains(value,'ENABLED'))
                app.rpmsg.Adrv9001_SetRadioState( port, channel, app.rpmsg.ENABLED );
            elseif(contains(value,'PRIMED'))
                app.rpmsg.Adrv9001_SetRadioState( port, channel, app.rpmsg.PRIMED );
            elseif(contains(value,'CALIBRATED'))
                app.rpmsg.Adrv9001_SetRadioState( port, channel, app.rpmsg.CALIBRATED );
            else
                app.rpmsg.Adrv9001_SetRadioState( port, channel, app.rpmsg.STANDBY );
            end

            app.UpdateLogText(['Adrv9001_SetRadioState' value]);

            state = app.App_GetRadioState(port, channel);

        end

        function UpdateLogText( app, s )

            if strcmp(app.LogText.Value,'')
                message_disp = s;
            else
                message_disp = [app.LogText.Value(:)', {s}];
            end
            app.LogText.Value = message_disp;
            drawnow
            scroll(app.LogText, 'bottom');
            drawnow

        end

        function struct2tree( app, parent, s )

            names = fieldnames(s);

            for i = 1:length(names)
                child = getfield(s,names{i});

                if( isnumeric( child ))
                    uitreenode( parent, "Text", [names{i} '=' num2str(child)] );
                elseif( islogical( child ))
                    uitreenode( parent, "Text", [names{i} '=' num2str(child)] );
                elseif( isenum( child ))
                    uitreenode( parent, "Text", [names{i} '=' char(child)] );
                elseif( isa(child,'AnalogDevices.EvalClient.Adrv9001ProfileTypes.adrv9001_RxChannelCfg_t[]') )
                    node = uitreenode(parent,"Text",names{i});
                    for j = 1: child.Length
                        app.struct2tree( node, child(j) );
                    end
                elseif( isa(child,'AnalogDevices.EvalClient.Adrv9001ProfileTypes.adrv9001_TxProfile_t[]') )
                    node = uitreenode(parent,"Text",names{i});
                    for j = 1: child.Length
                        app.struct2tree( node, child(j) );
                    end
                elseif( isa(child,'AnalogDevices.EvalClient.Adrv9001ProfileTypes.adrv9001_PfirPulseBuffer_t[]') )
                    node = uitreenode(parent,"Text",names{i});
                    for j = 1: child.Length
                        app.struct2tree( node, child(j) );
                    end
                elseif( isa(child,'AnalogDevices.EvalClient.Adrv9001ProfileTypes.adrv9001_PfirMag21Buffer_t[]') )
                    node = uitreenode(parent,"Text",names{i});
                    for j = 1: child.Length
                        app.struct2tree( node, child(j) );
                    end
                elseif( isa(child,'AnalogDevices.EvalClient.Adrv9001ProfileTypes.adrv9001_PfirMag13Buffer_t[]') )
                    node = uitreenode(parent,"Text",names{i});
                    for j = 1: child.Length
                        app.struct2tree( node, child(j) );
                    end
                elseif(isa(child,'System.Int16[]') || isa(child,'System.UInt16[]') || isa(child,'System.UInt32[]') || isa(child,'System.Int32[]'))

                    str = [names{i} ' = '];
                    for j = 1:child.Length
                        str = [str num2str(child(j)) ', '];
                    end
                    uitreenode(parent,"Text",str);

                elseif( ~isempty(fieldnames(child)) )
                    node = uitreenode(parent,"Text",names{i});
                    app.struct2tree( node, child );
                end
            end

        end

        function App_WriteProfile( app )

            addpath([fileparts(mfilename('fullpath')) '\@adrv9001\']);
            d = uiprogressdlg(app.UIFigure,'Title','Loading Profile',...
                'Message','Initializing ADRV9001...');
            pause(.5);
            d.Value = .1;

            app.rpmsg.Adrv9001_Initialize( );

            d.Value = .4;
            d.Message = 'Calibrating ADRV9001...';

            app.rpmsg.Adrv9001_Calibrate( );

            d.Value = .6;
            d.Message = 'Configuring ADRV9001...';

            app.rpmsg.Adrv9001_Configure( );

            d.Value = .8;
            d.Message = 'Priming ADRV9001...';

            app.rpmsg.Adrv9001_Prime( );

            d.Value = .90;
            d.Message = 'Reading Profile Settings...';

            app.App_UpdateProfileInfo( );

            app.UpdateLogText('Profile Loaded');

            app.profileValid = true;

            % Close dialog box
            close(d);
        end

        function App_LoadProfile( app )
            app.profileDir = uigetdir([app.profileDir '\..\'],'Profile Directory' );
            %app.rpmsg.Adrv9001_LoadProfile( app.profileDir );

            app.UpdateLogText('Loading Profile files: ');
            s = string(ls(app.profileDir));
            for i = 3:length(s)
                app.UpdateLogText(s{i});
            end
        end

        function App_UpdateProfileInfo( app )
            a = app.ProfileTree.Children;
            a.delete;
            app.struct2tree( app.ProfileTree, app.rpmsg.adrv9001_init );
        end

        function App_Adrv9001Connect( app )
            app.rpmsg.ipAddress = app.AddressEditField.Value;
            app.rpmsg.Adrv9001_Connect();
        end

        function App_PrintfCallback( app, src, evnt )
            app.UpdateLogText( char(app.rpmsg.status_string) );
        end

        function App_UpdateConnectionInfo( app, connected )
            if( connected )
                app.ConnectedTool.Icon = 'on_01.png';
                app.ConnectionTypeDropDown.Enable = false;
                app.AddressEditField.Enable = false;
                app.ConnectButton.Text = 'Disconnect';

                info  = app.rpmsg.Adrv9001_GetVerInfo();

                app.UpdateLogText('Connected');

                app.connection_info.Data = {...
                    app.ConnectionTypeDropDown.Value,...
                    app.rpmsg.ipAddress,...
                    info.BoardName,...
                    info.BoardRevision,...
                    info.TransceiverName,...
                    info.SiliconRevision,...
                    info.FirmwareVersion,...
                    info.FPGAVersion,...
                    info.APIVersion}';

                app.connection_info.RowName = {...
                    'Connection Type',...
                    'Connection Address',...
                    'Board Name',...
                    'Board Revision',...
                    'Transceiver Name',...
                    'Transceiver Silicon Revision',...
                    'Transceiver Firmware Revision',...
                    'Transceiver API Revision',...
                    'Transceiver FPGA Driver'}';
            else
                app.ConnectedTool.Icon = 'off_01.png';
                app.ConnectionTypeDropDown.Enable = true;
                app.AddressEditField.Enable = true;
                app.ConnectButton.Text = 'Connect';
                app.UpdateLogText( 'Disconnected' );
            end

        end

        function App_UpdateTxSettingsTable( app, channel )

            if( app.profileValid )
                fs = app.rpmsg.Adrv9001_GetSampleRate(app.rpmsg.PORT_TX, channel );
                fc = app.rpmsg.Adrv9001_GetCarrierFrequency(app.rpmsg.PORT_TX, channel );
                attn = app.rpmsg.Adrv9001_GetTxAttn( channel );
                boost = app.rpmsg.Adrv9001_GetTxBoost( channel );
            else
                fs = 0;
                fc = 0;
                attn = 10;
                boost = false;
            end

            app.Tx1SettingsTable.Data = {fs/1e6, 'MHz'; fc/1e6, 'MHz'; attn, 'dB'; boost, '';app.Tx1Tone1Amp, 'dBFS'; app.Tx1Tone1Freq, 'KHz'; app.Tx1Tone2Amp, 'dBFS'; app.Tx1Tone2Freq, 'KHz'; };

        end

        function APP_SetCarrierFrequency( app, port, channel, freq )
            if( app.profileValid )
                app.rpmsg.Adrv9001_SetCarrierFrequency( port, channel, freq );
            end
        end

        function APP_SetIfFrequency( app, port, channel, freq )
            if( app.profileValid )
                app.rpmsg.Adrv9001_SetIfFrequency( port, channel, freq );
            end
        end

        function APP_SetTxBoost( app, channel, enabled )
            if( app.profileValid )
                app.rpmsg.Adrv9001_SetTxBoost( channel, enabled );
            end
        end

        function APP_SetTxDataSource( app, channel, value )
            if( app.profileValid )

            end
        end

        function APP_PlotTx( app, channel )


            if( channel == app.rpmsg.CHANNEL_1)
                fs = app.Tx1SampleRate.Value;
                iq = app.rpmsg.iqTx1.double;

            else
                fs = app.Tx2SampleRate.Value;
                iq = app.rpmsg.iqTx2.double;

            end
            idata = iq(1:2:end)/2^15;
            qdata = iq(2:2:end)/2^15;
            iq = idata + 1i*qdata;

            bins = 1024;
            h2 = spectrum.welch('Hamming',bins);
            spec = msspectrum(h2,iq,...
                'Fs',fs,...
                'SpectrumType','twosided',...
                'CenterDC',true);
            f = spec.Frequencies/1e6;
            a = 10*log10(spec.Data);
            a(length(a)/2-2:length(a)/2+2) = a(length(a)/2-3);

            plot(app.TxPlot, f,a);
            app.TxPlot.XLabel.String = 'Frequency (MHz)';
            app.TxPlot.YLabel.String = 'Power (dB)';

        end

        function APP_PlotRx( app )
            fs = app.Rx1SampleRate.Value;
            iq = app.rpmsg.iqRx1;


            bins = 1024;
            h2 = spectrum.welch('Hamming',bins);
            spec = msspectrum(h2,iq,...
                'Fs',fs,...
                'SpectrumType','twosided',...
                'CenterDC',true);
            f1 = spec.Frequencies/1e6;
            a1 = 10*log10(spec.Data);


            fs = app.Rx2SampleRate.Value;
            iq = app.rpmsg.iqRx2;
            bins = 1024;
            h2 = spectrum.welch('Hamming',bins);
            spec = msspectrum(h2,iq,...
                'Fs',fs,...
                'SpectrumType','twosided',...
                'CenterDC',true);
            f2 = spec.Frequencies/1e6;
            a2 = 10*log10(spec.Data);


            plot(app.RxPlot, [f1,f2],[a1,a2]);

            app.RxPlot.XLabel.String = 'Frequency (MHz)';
            app.RxPlot.YLabel.String = 'Power (dB)';

        end

        function App_RxTimerCb( app, obj, event )
            app.rpmsg.Adrv9001_DataCapture( );
            app.APP_PlotRx( );
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rpmsg = adrv9001();
            %app.rflan = bytepipe_rflan();
            
            addlistener(app.rpmsg,'status_string','PostSet',@app.App_PrintfCallback); % Add obj to argument list
            app.App_UpdateConnectionInfo( false );

            app.rxTimer = timer('Period',1,'ExecutionMode','fixedSpacing','TasksToExecute',Inf);
            app.rxTimer.TimerFcn = @app.App_RxTimerCb;

        end

        % Callback function: ConnectButton, ConnectTool, ConnectedTool
        function ConnectButtonCb(app, event)
            if( strcmp(app.ConnectButton.Text, 'Connect'))

                if( strcmp(app.ConnectionTypeDropDown.Value, 'RFLAN'))
                    
                else
                    app.App_Adrv9001Connect();
                end

                app.App_UpdateConnectionInfo( true );
            else
                app.App_UpdateConnectionInfo( false );
            end

        end

        % Cell edit callback: connection_info
        function ConnectInfoCb(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if( indices == [1,1])
                app.rpmsg.ipAddress = newData;
            end

        end

        % Value changed function: ConnectionTypeDropDown
        function ConnectionTypeDropDownCb(app, event)


        end

        % Button pushed function: SelectProfileButton
        function ProfileInitCb(app, event)
            app.App_LoadProfile( );
        end

        % Button pushed function: WriteProfile
        function WriteProfileButtonCb(app, event)
            app.App_WriteProfile( );
        end

        % Button pushed function: PFirReadButton
        function PFirReadButtonCb(app, event)

            if( app.profileValid )
                if( strcmp(app.PFirPort.Value,'PORT_RX') )
                    port = app.rpmsg.PORT_RX;
                else
                    port = app.rpmsg.PORT_TX;
                end

                if( strcmp(app.PFirChannel.Value, 'CHANNEL_1'))
                    channel = app.rpmsg.CHANNEL_1;
                else
                    channel = app.rpmsg.CHANNEL_2;
                end

                if( app.PFirAButton.Value )
                    coeff_set = 'A';
                elseif( app.PFirBButton.Value )
                    coeff_set = 'B';
                elseif( app.PFirCButton.Value )
                    coeff_set = 'C';
                else
                    coeff_set = 'D';
                end

                [x,y] = app.rpmsg.Adrv9001_GetPFirResponse( port, channel, coeff_set);

                fs = app.rpmsg.Adrv9001_GetSampleRate(port,channel);

                if( strcmp(app.FirFrequencyUnit.Value, 'MHz') )
                    x = x / 1e6;
                    fs = fs / 1e6;
                    app.PFirPlot.XLabel.String = 'Frequency (MHz)';
                elseif( strcmp(app.FirFrequencyUnit.Value, 'KHz') )
                    x = x / 1e3;
                    fs = fs / 1e3;
                    app.PFirPlot.XLabel.String = 'Frequency (KHz)';
                else
                    app.PFirPlot.XLabel.String = 'Frequency (Hz)';
                end

                plot(app.PFirPlot, x, y);
                grid on;
                app.PFirPlot.YLabel.String = 'Magnitude (dB)';

                app.FirFsEditField.Value = fs;

            else
                app.UpdateLogText(' Invalid Profile ');

            end
        end

        % Button pushed function: PFirWriteButton
        function PFirWriteCb(app, event)
            if( strcmp(app.PFirPort.Value,'PORT_RX') )
                port = app.rpmsg.PORT_RX;
            else
                port = app.rpmsg.PORT_TX;
            end

            if( strcmp(app.PFirChannel.Value, 'CHANNEL_1'))
                channel = app.rpmsg.CHANNEL_1;
            else
                channel = app.rpmsg.CHANNEL_2;
            end

            if( app.PFirAButton.Value )
                coeff_set = 'A';
            elseif( app.PFirBButton.Value )
                coeff_set = 'B';
            elseif( app.PFirCButton.Value )
                coeff_set = 'C';
            else
                coeff_set = 'D';
            end

            app.rpmsg.Adrv9001_SetPFirCoefficients( port, coeff_set, app.PFirDesignCoeff);
        end

        % Button pushed function: FirDesignButton
        function FirDesignButtonCb(app, event)
            resp = app.FirResponseType.Value;
            fpass = app.FirFpassEditField.Value;
            fstop = app.FirFstopEditField.Value;
            apass = app.FirApassEditField.Value;
            astop = app.FirAstopEditField.Value;
            fs = app.FirFsEditField.Value;

            if( strcmp(app.FirFrequencyUnit.Value, 'MHz'))
                fpass = fpass * 1e6;
                fstop = fstop*1e6;
                fs = fs * 1e6;
            elseif( strcmp(app.FirFrequencyUnit.Value, 'KHz'))
                fpass = fpass * 1e3;
                fstop = fstop *1e3;
                fs = fs*1e3;
            end

            d = designfilt(resp, ...
                'PassbandFrequency',fpass, ...
                'StopbandFrequency',fstop, ...
                'PassbandRipple',apass, ...
                'StopbandAttenuation',astop, ...
                'DesignMethod',app.FirDesignMethodDropDown.Value,...
                'SampleRate',fs);

            app.PFirDesignCoeff = d.Coefficients;

            h = freqz(d);
            h = 20*log10(abs(h));
            y = h;
            x = [0:fs/2/length(h):fs/2-1];
            x = double(x);

            if( strcmp(app.FirFrequencyUnit.Value, 'MHz') )
                x = x / 1e6;
                app.PFirPlot.XLabel.String = 'Frequency (MHz)';
            elseif( strcmp(app.FirFrequencyUnit.Value, 'KHz') )
                x = x / 1e3;
                app.PFirPlot.XLabel.String = 'Frequency (KHz)';
            else
                app.PFirPlot.XLabel.String = 'Frequency (Hz)';
            end

            plot(app.PFirPlot, x, y);
            grid on;
            app.PFirPlot.YLabel.String = 'Magnitude (dB)';

        end

        % Button down function: TransmitTab
        function TransmitTabEnterCb(app, event)
            app.Tx1RadioState.Value = app.App_GetRadioState(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1);
            app.Tx2RadioState.Value = app.App_GetRadioState(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_2);

            if( app.profileValid )
                app.Tx1SampleRate.Value = app.rpmsg.Adrv9001_GetSampleRate(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1 );
                app.Tx1IfFreq.Value = app.rpmsg.Adrv9001_GetIfFrequency(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1 );
                app.Tx1CarrierFreq.Value = app.rpmsg.Adrv9001_GetCarrierFrequency(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1 );
                app.Tx1Attn.Value = app.rpmsg.Adrv9001_GetTxAttn( app.rpmsg.CHANNEL_1 );
                app.Tx1BoostEnabled.Value = app.rpmsg.Adrv9001_GetTxBoost( app.rpmsg.CHANNEL_1 );

                app.Tx2SampleRate.Value = app.rpmsg.Adrv9001_GetSampleRate(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_2 );
                app.Tx2IfFreq.Value = app.rpmsg.Adrv9001_GetIfFrequency(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_2 );
                app.Tx2CarrierFreq.Value = app.rpmsg.Adrv9001_GetCarrierFrequency(app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_2 );
                app.Tx2Attn.Value = app.rpmsg.Adrv9001_GetTxAttn( app.rpmsg.CHANNEL_2 );
                app.Tx2BoostEnabled.Value = app.rpmsg.Adrv9001_GetTxBoost( app.rpmsg.CHANNEL_2 );
            end

        end

        % Value changed function: Tx1CarrierFreq
        function Tx1CarrierFreqCb(app, event)
            value = app.Tx1CarrierFreq.Value;
            app.APP_SetCarrierFrequency( app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1, value );
        end

        % Value changed function: Tx1IfFreq
        function Tx1IfFreqCb(app, event)
            value = app.Tx1IfFreq.Value;
            if( app.Tx1RadioState == 'STANDBY')
                app.APP_SetIfFrequency( app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1, value );
            else
                app.UpdateLogText('Error: Radio Must be in standby');
            end
        end

        % Value changed function: Tx1SampleRate
        function Tx1SampleRateCb(app, event)
            value = app.Tx1SampleRate.Value;
            if( app.Tx1RadioState == 'STANDBY')
                app.APP_SetSampleRate( app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1, value );
            else
                app.UpdateLogText('Error: Radio Must be in standby');
            end
        end

        % Value changed function: Tx1BoostEnabled
        function Tx1BoostCb(app, event)
            value = app.Tx1BoostEnabled.Value;
            app.APP_SetTxBoost( app.rpmsg.CHANNEL_1, value );
        end

        % Value changed function: Tx1Attn
        function Tx1AttnCb(app, event)
            value = app.Tx1Attn.Value;

            if( app.profileValid )
                app.rpmsg.Adrv9001_SetTxAttn( app.rpmsg.CHANNEL_1, value );
            end
        end

        % Callback function
        function Tx1DataSrcCb(app, event)
            value = app.Tx1DataSource.Value;
            app.APP_SetTxDataSource( app.rpmsg.CHANNEL_1, value );
        end

        % Value changed function: Tx1RadioState
        function Tx1RadioStateCb(app, event)
            value = app.Tx1RadioState.Value;
            app.App_SetRadioState( app.rpmsg.PORT_TX, app.rpmsg.CHANNEL_1, value );
        end

        % Value changed function: Tx1EnableButton
        function Tx1EnableCb(app, event)

            if( strcmp(app.Tx1EnableButton.Text, 'Enable'))
                app.rpmsg.Adrv9001_BeginTransmitting( );
                app.Tx1EnableButton.Text = 'Disable';

                app.APP_PlotTx( app.rpmsg.CHANNEL_1 );
                %app.APP_PlotTx( app.rpmsg.CHANNEL_2 );

            else
                app.rpmsg.Adrv9001_StopTransmitting( );
                app.Tx1EnableButton.Text = 'Enable';
            end

        end

        % Button down function: ReceiveTab
        function ReceiveTabEnter(app, event)
            app.Rx1RadioState.Value = app.App_GetRadioState(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_1);
            app.Rx2RadioState.Value = app.App_GetRadioState(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_2);

            if( app.profileValid )
                app.Rx1SampleRate.Value = app.rpmsg.Adrv9001_GetSampleRate(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_1 );
                app.Rx1IfFreq.Value = app.rpmsg.Adrv9001_GetIfFrequency(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_1 );
                app.Rx1CarrierFreq.Value = app.rpmsg.Adrv9001_GetCarrierFrequency(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_1 );
                app.Rx1Gain.Value = 0;%app.rpmsg.Adrv9001_GetTxAttn( app.rpmsg.CHANNEL_1 );


                app.Rx2SampleRate.Value = app.rpmsg.Adrv9001_GetSampleRate(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_2 );
                app.Rx2IfFreq.Value = app.rpmsg.Adrv9001_GetIfFrequency(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_2 );
                app.Rx2CarrierFreq.Value = app.rpmsg.Adrv9001_GetCarrierFrequency(app.rpmsg.PORT_RX, app.rpmsg.CHANNEL_2 );
                app.Rx2Gain.Value = 0;%app.rpmsg.Adrv9001_GetTxAttn( app.rpmsg.CHANNEL_2 );

            end
        end

        % Value changed function: Rx1EnableButton
        function ReceiveEnableCb(app, event)
            if( strcmp(app.Rx1EnableButton.Text, 'Enable'))
                app.rpmsg.Adrv9001_BeginReceiving();
                app.Rx1EnableButton.Text = 'Disable';
                start(app.rxTimer);
            else
                app.rpmsg.Adrv9001_StopReceiving( );
                app.Rx1EnableButton.Text = 'Enable';
                stop(app.rxTimer);

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ConnectTool = uipushtool(app.Toolbar);
            app.ConnectTool.Tooltip = {'Connect'};
            app.ConnectTool.ClickedCallback = createCallbackFcn(app, @ConnectButtonCb, true);
            app.ConnectTool.Icon = 'connect_01.png';
            app.ProfileBrowseTool = uipushtool(app.Toolbar);
            app.ProfileBrowseTool.Tooltip = {'Load Profile'};
            app.ProfileBrowseTool.Icon = 'folder_open_01.png';
            app.ProfileBrowseTool.Separator = 'on';
            app.ConnectedTool = uitoggletool(app.Toolbar);
            app.ConnectedTool.Tooltip = {'Connection Status'};
            app.ConnectedTool.ClickedCallback = createCallbackFcn(app, @ConnectButtonCb, true);
            app.ConnectedTool.Icon = 'off_01.png';
            app.ConnectedTool.State = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonCb, true);
            app.ConnectButton.Text = 'Connect';
            app.connection_info = uitable(app.ConnectionTab);
            app.connection_info.ColumnName = {''};
            app.connection_info.RowName = {};
            app.connection_info.ColumnEditable = true;
            app.connection_info.CellEditCallback = createCallbackFcn(app, @ConnectInfoCb, true);
            app.Logo = uiimage(app.ConnectionTab);
            app.Logo.ImageSource = 'Gold.jpg';
            app.ConnectionTypeLabel = uilabel(app.ConnectionTab);
            app.ConnectionTypeLabel.Text = 'Connection Type';
            app.ConnectionTypeDropDown = uidropdown(app.ConnectionTab);
            app.ConnectionTypeDropDown.Items = {'Adrv9001Server', 'RflanServer'};
            app.ConnectionTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectionTypeDropDownCb, true);
            app.ConnectionTypeDropDown.Value = 'Adrv9001Server';
            app.AddressEditFieldLabel = uilabel(app.ConnectionTab);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.ConnectionTab, 'text');
            app.AddressEditField.Value = '192.168.1.10';
            app.ProfileTab = uitab(app.TabGroup);
            app.ProfileTab.Title = 'Profile';
            app.SelectProfileButton = uibutton(app.ProfileTab, 'push');
            app.SelectProfileButton.ButtonPushedFcn = createCallbackFcn(app, @ProfileInitCb, true);
            app.SelectProfileButton.Text = 'Select Profile';
            app.WriteProfile = uibutton(app.ProfileTab, 'push');
            app.WriteProfile.ButtonPushedFcn = createCallbackFcn(app, @WriteProfileButtonCb, true);
            app.WriteProfile.Text = 'Write Profile';
            app.ProfileTree = uitree(app.ProfileTab);
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.PFirPlot = uiaxes(app.FilterTab);
            title(app.PFirPlot, ' ')
            xlabel(app.PFirPlot, 'X')
            ylabel(app.PFirPlot, 'Y')
            zlabel(app.PFirPlot, 'Z')
            app.Adrv9001Panel = uipanel(app.FilterTab);
            app.Adrv9001Panel.Title = 'Adrv9001';
            app.PFirCoefficientSetButtonGroup = uibuttongroup(app.Adrv9001Panel);
            app.PFirCoefficientSetButtonGroup.Title = 'Coefficient Set';
            app.PFirAButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirAButton.Text = 'PFir A';
            app.PFirAButton.Value = true;
            app.PFirBButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirBButton.Text = 'PFir B';
            app.PFirCButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirCButton.Text = 'PFir C';
            app.PFirDButton = uiradiobutton(app.PFirCoefficientSetButtonGroup);
            app.PFirDButton.Text = 'PFir D';
            app.PFirPort = uidropdown(app.Adrv9001Panel);
            app.PFirPort.Items = {'PORT_RX', 'PORT_TX'};
            app.PFirPort.Value = 'PORT_RX';
            app.PFirChannel = uidropdown(app.Adrv9001Panel);
            app.PFirChannel.Items = {'CHANNEL_1', 'CHANNEL_2'};
            app.PFirChannel.Value = 'CHANNEL_1';
            app.PFirReadButton = uibutton(app.Adrv9001Panel, 'push');
            app.PFirReadButton.ButtonPushedFcn = createCallbackFcn(app, @PFirReadButtonCb, true);
            app.PFirReadButton.Text = 'Read';
            app.PFirWriteButton = uibutton(app.Adrv9001Panel, 'push');
            app.PFirWriteButton.ButtonPushedFcn = createCallbackFcn(app, @PFirWriteCb, true);
            app.PFirWriteButton.Text = 'Write';
            app.FilterDesignerPanel = uipanel(app.FilterTab);
            app.FilterDesignerPanel.Title = 'Filter Designer';
            app.FirDesignMethodDropDown = uidropdown(app.FilterDesignerPanel);
            app.FirDesignMethodDropDown.Items = {'butter', 'cheby1', 'cheby2', 'cls', 'ellip', 'equiripple', 'freqsamp', 'kaiserwin', 'ls', 'maxflat', 'window'};
            app.FirDesignMethodDropDown.Value = 'equiripple';
            app.AstopEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.AstopEditFieldLabel.Text = 'Astop';
            app.FirAstopEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.ApassEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.ApassEditFieldLabel.Text = 'Apass';
            app.FirApassEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FstopEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.FstopEditFieldLabel.Text = 'Fstop';
            app.FirFstopEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FpassEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.FpassEditFieldLabel.Text = 'Fpass';
            app.FirFpassEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FsEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FirFsEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FirFrequencyUnit = uidropdown(app.FilterDesignerPanel);
            app.FirFrequencyUnit.Items = {'Hz', 'KHz', 'MHz'};
            app.FirFrequencyUnit.Value = 'MHz';
            app.OrderEditFieldLabel = uilabel(app.FilterDesignerPanel);
            app.OrderEditFieldLabel.Enable = 'off';
            app.OrderEditFieldLabel.Text = 'Order';
            app.FirOrderEditField = uieditfield(app.FilterDesignerPanel, 'numeric');
            app.FirOrderEditField.Editable = 'off';
            app.FirOrderEditField.Enable = 'off';
            app.FirOrderEditField.Value = 128;
            app.FirDesignButton = uibutton(app.FilterDesignerPanel, 'push');
            app.FirDesignButton.ButtonPushedFcn = createCallbackFcn(app, @FirDesignButtonCb, true);
            app.FirDesignButton.Text = 'Design';
            app.FirResponseType = uidropdown(app.FilterDesignerPanel);
            app.FirResponseType.Items = {'lowpassfir', 'lowpassiir', 'highpassfir', 'highpassiir', 'bandpassfir', 'bandpassiir', 'bandstopfir', 'bandstopiir', 'differentiatorfir', 'hilbertfir', 'arbmagfir'};
            app.FirResponseType.Value = 'lowpassfir';
            app.FirAmplitudeUnit = uidropdown(app.FilterDesignerPanel);
            app.FirAmplitudeUnit.Items = {'dB'};
            app.FirAmplitudeUnit.Enable = 'off';
            app.FirAmplitudeUnit.Value = 'dB';
            app.TransmitTab = uitab(app.TabGroup);
            app.TransmitTab.Title = 'Transmit';
            app.TransmitTab.ButtonDownFcn = createCallbackFcn(app, @TransmitTabEnterCb, true);
            app.TxPlot = uiaxes(app.TransmitTab);
            title(app.TxPlot, ' ')
            xlabel(app.TxPlot, 'X')
            ylabel(app.TxPlot, 'Y')
            zlabel(app.TxPlot, 'Z')
            app.Tx1Panel = uipanel(app.TransmitTab);
            app.Tx1Panel.Title = 'Tx1';
            app.Tx1BoostEnabled = uicheckbox(app.Tx1Panel);
            app.Tx1BoostEnabled.ValueChangedFcn = createCallbackFcn(app, @Tx1BoostCb, true);
            app.Tx1BoostEnabled.Text = 'Boost Enabled';
            app.FrequencyMHzLabel = uilabel(app.Tx1Panel);
            app.FrequencyMHzLabel.Text = 'Frequency (MHz)';
            app.Tx1CarrierFreq = uieditfield(app.Tx1Panel, 'numeric');
            app.Tx1CarrierFreq.ValueChangedFcn = createCallbackFcn(app, @Tx1CarrierFreqCb, true);
            app.AttenuationdBEditFieldLabel = uilabel(app.Tx1Panel);
            app.AttenuationdBEditFieldLabel.Text = 'Attenuation (dB)';
            app.Tx1Attn = uieditfield(app.Tx1Panel, 'numeric');
            app.Tx1Attn.ValueChangedFcn = createCallbackFcn(app, @Tx1AttnCb, true);
            app.SampleRateMHzLabel = uilabel(app.Tx1Panel);
            app.SampleRateMHzLabel.Text = 'Sample Rate (MHz)';
            app.Tx1SampleRate = uieditfield(app.Tx1Panel, 'numeric');
            app.Tx1SampleRate.ValueChangedFcn = createCallbackFcn(app, @Tx1SampleRateCb, true);
            app.IFFreqKHzLabel = uilabel(app.Tx1Panel);
            app.IFFreqKHzLabel.Text = 'IF Freq (KHz)';
            app.Tx1IfFreq = uieditfield(app.Tx1Panel, 'numeric');
            app.Tx1IfFreq.ValueChangedFcn = createCallbackFcn(app, @Tx1IfFreqCb, true);
            app.RadioStateDropDownLabel = uilabel(app.Tx1Panel);
            app.RadioStateDropDownLabel.Text = 'Radio State';
            app.Tx1RadioState = uidropdown(app.Tx1Panel);
            app.Tx1RadioState.Items = {'ENABLED', 'PRIMED', 'CALIBRATED', 'STANDBY'};
            app.Tx1RadioState.ValueChangedFcn = createCallbackFcn(app, @Tx1RadioStateCb, true);
            app.Tx1RadioState.Value = 'ENABLED';
            app.DataSourceDropDownLabel = uilabel(app.Tx1Panel);
            app.DataSourceDropDownLabel.Text = 'Data Source';
            app.DataSourceDropDown = uidropdown(app.Tx1Panel);
            app.DataSourceDropDown.Items = {'File', 'Zeros', 'Single Tone', 'Dual Tone'};
            app.DataSourceDropDown.Value = 'File';
            app.Tx2Panel = uipanel(app.TransmitTab);
            app.Tx2Panel.Title = 'Tx2';
            app.Tx2BoostEnabled = uicheckbox(app.Tx2Panel);
            app.Tx2BoostEnabled.Text = 'Boost Enabled';
            app.FrequencyMHzLabel_2 = uilabel(app.Tx2Panel);
            app.FrequencyMHzLabel_2.Text = 'Frequency (MHz)';
            app.Tx2CarrierFreq = uieditfield(app.Tx2Panel, 'numeric');
            app.AttenuationdBEditFieldLabel_2 = uilabel(app.Tx2Panel);
            app.AttenuationdBEditFieldLabel_2.Text = 'Attenuation (dB)';
            app.Tx2Attn = uieditfield(app.Tx2Panel, 'numeric');
            app.SampleRateMHzLabel_2 = uilabel(app.Tx2Panel);
            app.SampleRateMHzLabel_2.Text = 'Sample Rate (MHz)';
            app.Tx2SampleRate = uieditfield(app.Tx2Panel, 'numeric');
            app.IFFreqKHzLabel_2 = uilabel(app.Tx2Panel);
            app.IFFreqKHzLabel_2.Text = 'IF Freq (KHz)';
            app.Tx2IfFreq = uieditfield(app.Tx2Panel, 'numeric');
            app.RadioStateDropDownLabel_2 = uilabel(app.Tx2Panel);
            app.RadioStateDropDownLabel_2.Text = 'Radio State';
            app.Tx2RadioState = uidropdown(app.Tx2Panel);
            app.Tx2RadioState.Items = {'ENABLED', 'PRIMED', 'CALIBRATED', 'STANDBY'};
            app.Tx2RadioState.Value = 'ENABLED';
            app.DataSourceDropDown_2Label = uilabel(app.Tx2Panel);
            app.DataSourceDropDown_2Label.Text = 'Data Source';
            app.DataSourceDropDown_2 = uidropdown(app.Tx2Panel);
            app.DataSourceDropDown_2.Items = {'File', 'Zeros', 'Single Tone', 'Dual Tone'};
            app.DataSourceDropDown_2.Value = 'File';
            app.Tx1EnableButton = uibutton(app.TransmitTab, 'state');
            app.Tx1EnableButton.ValueChangedFcn = createCallbackFcn(app, @Tx1EnableCb, true);
            app.Tx1EnableButton.Text = 'Enable';
            app.ReceiveTab = uitab(app.TabGroup);
            app.ReceiveTab.Title = 'Receive';
            app.ReceiveTab.ButtonDownFcn = createCallbackFcn(app, @ReceiveTabEnter, true);
            app.RxPlot = uiaxes(app.ReceiveTab);
            title(app.RxPlot, ' ')
            xlabel(app.RxPlot, 'X')
            ylabel(app.RxPlot, 'Y')
            zlabel(app.RxPlot, 'Z')
            app.Rx1Panel = uipanel(app.ReceiveTab);
            app.Rx1Panel.Title = 'Rx1';
            app.FrequencyMHzLabel_3 = uilabel(app.Rx1Panel);
            app.FrequencyMHzLabel_3.Text = 'Frequency (MHz)';
            app.Rx1CarrierFreq = uieditfield(app.Rx1Panel, 'numeric');
            app.GaindBLabel = uilabel(app.Rx1Panel);
            app.GaindBLabel.Text = 'Gain (dB)';
            app.Rx1Gain = uieditfield(app.Rx1Panel, 'numeric');
            app.SampleRateMHzLabel_3 = uilabel(app.Rx1Panel);
            app.SampleRateMHzLabel_3.Text = 'Sample Rate (MHz)';
            app.Rx1SampleRate = uieditfield(app.Rx1Panel, 'numeric');
            app.IFFreqKHzLabel_3 = uilabel(app.Rx1Panel);
            app.IFFreqKHzLabel_3.Text = 'IF Freq (KHz)';
            app.Rx1IfFreq = uieditfield(app.Rx1Panel, 'numeric');
            app.RadioStateDropDownLabel_3 = uilabel(app.Rx1Panel);
            app.RadioStateDropDownLabel_3.Text = 'Radio State';
            app.Rx1RadioState = uidropdown(app.Rx1Panel);
            app.Rx1RadioState.Items = {'ENABLED', 'PRIMED', 'CALIBRATED', 'STANDBY'};
            app.Rx1RadioState.Value = 'ENABLED';
            app.Rx2Panel = uipanel(app.ReceiveTab);
            app.Rx2Panel.Title = 'Rx2';
            app.FrequencyMHzLabel_4 = uilabel(app.Rx2Panel);
            app.FrequencyMHzLabel_4.Text = 'Frequency (MHz)';
            app.Rx2CarrierFreq = uieditfield(app.Rx2Panel, 'numeric');
            app.AttenuationdBEditFieldLabel_4 = uilabel(app.Rx2Panel);
            app.AttenuationdBEditFieldLabel_4.Text = 'Attenuation (dB)';
            app.Rx2Gain = uieditfield(app.Rx2Panel, 'numeric');
            app.SampleRateMHzLabel_4 = uilabel(app.Rx2Panel);
            app.SampleRateMHzLabel_4.Text = 'Sample Rate (MHz)';
            app.Rx2SampleRate = uieditfield(app.Rx2Panel, 'numeric');
            app.IFFreqKHzLabel_4 = uilabel(app.Rx2Panel);
            app.IFFreqKHzLabel_4.Text = 'IF Freq (KHz)';
            app.Rx2IfFreq = uieditfield(app.Rx2Panel, 'numeric');
            app.RadioStateDropDownLabel_4 = uilabel(app.Rx2Panel);
            app.RadioStateDropDownLabel_4.Text = 'Radio State';
            app.Rx2RadioState = uidropdown(app.Rx2Panel);
            app.Rx2RadioState.Items = {'ENABLED', 'PRIMED', 'CALIBRATED', 'STANDBY'};
            app.Rx2RadioState.Value = 'ENABLED';
            app.Rx1EnableButton = uibutton(app.ReceiveTab, 'state');
            app.Rx1EnableButton.ValueChangedFcn = createCallbackFcn(app, @ReceiveEnableCb, true);
            app.Rx1EnableButton.Text = 'Enable';
            app.LogText = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bytepipe_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end